classdef BEM_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BEMT_App                      matlab.ui.Figure
        InputTable                    matlab.ui.control.Table
        CalculateButton               matlab.ui.control.Button
        AddRowButton                  matlab.ui.control.Button
        DeleteRowButton               matlab.ui.control.Button
        EditField                     matlab.ui.control.NumericEditField
        OutputTable                   matlab.ui.control.Table
        CpCurveButton                 matlab.ui.control.Button
        PowerCurveButton              matlab.ui.control.Button
        ThrustCurveButton             matlab.ui.control.Button
        TorqueCurveButton             matlab.ui.control.Button
        CpLabel                       matlab.ui.control.Label
        AerofoilDropDownLabel         matlab.ui.control.Label
        AerofoilDropDown              matlab.ui.control.DropDown
        NumberofBladesEditFieldLabel  matlab.ui.control.Label
        NumberofBladesEditField       matlab.ui.control.NumericEditField
        TidalSpeedEditFieldLabel      matlab.ui.control.Label
        TidalSpeedEditField           matlab.ui.control.NumericEditField
        TipSpeedRatioEditFieldLabel   matlab.ui.control.Label
        TipSpeedRatioEditField        matlab.ui.control.NumericEditField
        DesignInputsLabel             matlab.ui.control.Label
        DesignResultsLabel            matlab.ui.control.Label
        BladeElementMomentumTheoryBladeDesignerLabel  matlab.ui.control.Label
        EditFieldPower                matlab.ui.control.NumericEditField
        PowerLabel                    matlab.ui.control.Label
        EditFieldThrust               matlab.ui.control.NumericEditField
        EditFieldTorque               matlab.ui.control.NumericEditField
        ThrustLabel                   matlab.ui.control.Label
        TorqueLabel                   matlab.ui.control.Label
        ExampleButton                 matlab.ui.control.Button
        SaveDesignButton              matlab.ui.control.Button
        RatedPowerkWLabel             matlab.ui.control.Label
        RatedPowerEditField           matlab.ui.control.NumericEditField
        TipLossCheckBox               matlab.ui.control.CheckBox
        Switch                        matlab.ui.control.Switch
        PowerLabel_2                  matlab.ui.control.Label
        ThrustLabel_2                 matlab.ui.control.Label
        TorqueLabel_2                 matlab.ui.control.Label
    end


    properties (Access = private)
        % To be able to use throughout program
        H = 6; % Number of rows within the data table
        r = 0; % Radius matrix
        gamma = 0; % Gamma matrix
        c = 0; % Chord Length matrix
        B = 0;  % Number of blades
        R = 0;  %Radius
        lambda = 0; %Tip speed ratio
        V = 0;  %Tidal Velocity
        cp = 0; % Power coeff
        T = 0; %Data output table to be saved to file
        Q = 'N'; %tip loss correction, no tip loss unless selected
        aerofoil = 1;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.InputTable.Data = {5, 92.6, 0.19}; % Create initial values for the table
            app.InputTable.Data = {0.4,61,0.7;1,74.3,0.71;2,84.9,0.44;3,89.1,0.3;4,91.3,0.23;5,92.6,0.19};
            
            for z = 1 : app.H %Stores values from input table into vectors
                app.r(z) = cell2mat(app.InputTable.Data(z,1));
                app.gamma(z) = cell2mat(app.InputTable.Data(z,2));
                app.c(z) = cell2mat(app.InputTable.Data(z,3));
            end
            
            %Hide functions only available after results are calculated
            app.OutputTable.Visible = 'off';
            app.EditField.Visible = 'off';
            app.CpCurveButton.Visible = 'off';
            app.PowerCurveButton.Visible = 'off';
            app.ThrustCurveButton.Visible = 'off';
            app.TorqueCurveButton.Visible = 'off';
            app.CpLabel.Visible = 'off';
            app.DesignResultsLabel.Visible = 'off';
            app.PowerLabel.Visible = 'off';
            app.EditFieldPower.Visible = 'off';
            app.EditFieldThrust.Visible = 'off';
            app.EditFieldTorque.Visible = 'off';
            app.ThrustLabel.Visible = 'off';
            app.TorqueLabel.Visible = 'off';
            app.SaveDesignButton.Visible = 'off';
            app.Switch.Visible = 'off';
            app.PowerLabel_2.Visible = 'off';
            app.ThrustLabel_2.Visible = 'off';
            app.TorqueLabel_2.Visible = 'off';
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
            app.Switch.Value = 'k';
            app.PowerLabel_2.Visible = 'off';
            app.ThrustLabel_2.Visible = 'off';
            app.TorqueLabel_2.Visible = 'off';
            
            for z = 1 : app.H %Stores values from input table into vectors
                app.r(z) = cell2mat(app.InputTable.Data(z,1));
                app.gamma(z) = cell2mat(app.InputTable.Data(z,2));
                app.c(z) = cell2mat(app.InputTable.Data(z,3));
            end
            
%             disp(app.H);
%             disp(app.r);
%             disp(app.gamma);
%             disp(app.c);
            

            app.B = app.NumberofBladesEditField.Value;              % Number of blades;
            app.R = app.r(app.H);              % Rotor radius
            app.lambda = app.TipSpeedRatioEditField.Value;         % Tip speed ratio
            app.V = app.TidalSpeedEditField.Value;              % Expected wind velocity
            
            [app.cp,a,a_,BetaDeg,i,Thrust,Torque] = DesignCalcs(app.B,app.R,app.lambda,app.V,app.r,app.c,app.gamma,app.H - 1,app.Q,app.aerofoil); 
            
            Power = app.cp * 0.5 * 1 * (pi * app.R.^2) * (app.V.^3);    %Power in kW
            
            
            %% Display Results 
            app.EditField.Value = app.cp;
            app.EditFieldPower.Value = Power;
            app.EditFieldThrust.Value = Thrust;
            app.EditFieldTorque.Value = Torque;
            
            app.T = table(a.',a_.',i.',BetaDeg.');
            app.OutputTable.Data = table2array(app.T);
            
            %% Make features visible
            app.OutputTable.Visible = 'on';
            app.EditField.Visible = 'on';
            app.CpCurveButton.Visible = 'on';
            app.PowerCurveButton.Visible = 'on';
            app.ThrustCurveButton.Visible = 'on';
            app.TorqueCurveButton.Visible = 'on';
            app.CpLabel.Visible = 'on';
            app.DesignResultsLabel.Visible = 'on';
            app.PowerLabel.Visible = 'on';
            app.EditFieldPower.Visible = 'on';
            app.EditFieldThrust.Visible = 'on';
            app.EditFieldTorque.Visible = 'on';
            app.ThrustLabel.Visible = 'on';
            app.TorqueLabel.Visible = 'on';
            app.SaveDesignButton.Visible = 'on';
            app.Switch.Visible = 'on';
            
        end

        % Cell edit callback: InputTable
        function InputTableCellEdit(app, event)
            
        end

        % Button pushed function: AddRowButton
        function AddRowButtonPushed(app, event)
          % adds a new row of data with values 0 0 0
          newData= {0 0 0}; 
          app.InputTable.Data = [app.InputTable.Data ; newData]; % Add 1 row to table
          
          app.H = app.H + 1;
          app.r(app.H) = 0;
          app.gamma(app.H) = 0;
          app.c(app.H) = 0;
        end

        % Button pushed function: DeleteRowButton
        function DeleteRowButtonPushed(app, event)
           
            app.InputTable.Data(end,:)=[];  % remove last row of table
            app.r(app.H) = [];
            app.gamma(app.H) = [];
            app.c(app.H) = [];
            
            app.H = app.H - 1;
        end

        % Button pushed function: CpCurveButton
        function CpCurveButtonPushed(app, event)
            fig = uifigure();
            ax = uiaxes(fig,'Position',[10 10 525 400]);
            ax.Title.String = "Cp - TSR curve";
            ax.YLabel.String = "Cp";
            ax.XLabel.String = "TSR";
            
            % calculate Cp with increasing tip speed ratio to plot graph
            s = 20;
            for lambdaRange = 1:s
                [CpRange(lambdaRange)] = DesignCalcs(app.B,app.R,lambdaRange,app.V,app.r,app.c,app.gamma,app.H - 1,app.Q,app.aerofoil);
            end
              
            [m,n] = size(CpRange);
            
            % if random result, changes results to be in line to plot a smoother curve
%             for x = 1:s - 1
%                 if x == s
%                     break
%                 end
%                 %if abs(((CpRange(x) - abs(CpRange(x+1))))) > 0.2
%                 if abs(((CpRange(x) - abs(CpRange(x+1))) / CpRange(x)) * 100) > 75
%                     CpRange(x+1) = CpRange(x) + ((CpRange(x)/100)*15);
%                 end
%             end
            
            MaxPos = find(CpRange==max(CpRange));
            for x = 2:MaxPos
                if x == s
                    break
                end
                if abs(CpRange(x) - CpRange(x-1)) > 0.2
                    CpRange(x) = CpRange(x-1) + ((CpRange(x-1)/100)*15);
                end
            end
            for x = MaxPos:s-1
                if x == s
                    break
                end
                if abs(CpRange(x) - CpRange(x-1)) > 0.2
                    CpRange(x) = CpRange(x-1) - ((CpRange(x-1)/100)*15);
                end
            end

            %for large values of tsr results invalid therefore removes all values after they become unstable
            for q = 2 : s - 1
                if CpRange(q) < CpRange(q-1) && CpRange(q+1) > CpRange(q)
                    for k = q + 1 : s
                        CpRange(q+1) = [];
                        [m,n] = size(CpRange);
                        if k == n
                            break
                        end
                    end
                    break
                end
            end
            s = n;
            
            
            %plot(ax,1:s,CpRange);              
            
            p = polyfit(1:s,CpRange,4);
            x2 = 1:s;
            y2 = polyval(p,x2);
            plot (ax,1:s,CpRange,'+',x2,y2);
            %plot (ax,x2,y2);
            ax.XLim = [0 20];
            ax.YLim = [0 0.6];
            
        end

        % Button pushed function: PowerCurveButton
        function PowerCurveButtonPushed(app, event)
            fig = uifigure();
            ax = uiaxes(fig,'Position',[10 10 525 400]);
            title = num2str(app.TipSpeedRatioEditField.Value);
            Title = strcat("Power-Velocity Curve : Tip Speed Ratio = ",title);
            ax.Title.String = Title;
            ax.YLabel.String = "Power [kW]";
            ax.XLabel.String = "Velocity [m/s]";
            
            count = 1;
            stepsize = 0.01;     %controls how many data points, allows more accurate interpolation
            for Velocity = 0:stepsize:6
                PowerRange(count) = 0.5 * 1 * (pi*app.R^2) * app.cp * (Velocity)^3;
                if PowerRange(count) >= app.RatedPowerEditField.Value
                    break
                end
                count = count + 1;
            end
            if count == 602
                count = 601;
            end
            %plot(ax,1:50,PowerRange);
            
            %scatter(ax,0:stepsize:(count*stepsize)-stepsize,PowerRange,5)

            p2 = polyfit(0:stepsize:(count*stepsize)-stepsize,PowerRange,4);
            x3 = 0:stepsize:(count*stepsize)-stepsize;
            y3 = polyval(p2,x3);
            %plot(ax,x3,y3,'r');

            %plot(ax,[3 5],[PowerRange(21) PowerRange(21)],'r',x3,y3,'r',1:0.1:3,PowerRange,'+');
            plot(ax,[Velocity 5],[PowerRange(count) PowerRange(count)],'r',x3,y3,'r');
            ax.YLim = [0 (PowerRange(count)*1.2)];
            
        end

        % Button pushed function: ExampleButton
        function ExampleButtonPushed(app, event)
            app.InputTable.Data = {0.4,61,0.7;1,74.3,0.71;2,84.9,0.44;3,89.1,0.3;4,91.3,0.23;5,92.6,0.19};
            app.H = 6;
        end

        % Button pushed function: SaveDesignButton
        function SaveDesignButtonPushed(app, event)
            %saves input and output data to a spreadsheet file, user inputs file name
            prompt = {'Enter File Name: '};
            title = 'Save Design';
            defInput = {'MyDesign'};
            dims = [1 40];
            FileName = inputdlg(prompt,title,dims,defInput);
            if size(FileName) > 0 
                FileType = '.xlsx';
                FileResults = table(app.r.', app.gamma.', app.c.');
                FileResults.Properties.VariableNames = {'r','Gamma','c'};
                filename = strcat(char(FileName),FileType);
                outputTable = [FileResults app.T];
                outputTable.Properties.VariableNames = {'r','Gamma','c','a','a_','i','Beta'};
                writetable(outputTable,filename);
                
            end

        end

        % Button pushed function: ThrustCurveButton
        function ThrustCurveButtonPushed(app, event)
            fig = uifigure();
            ax = uiaxes(fig,'Position',[10 10 525 400]);
            title = num2str(app.TidalSpeedEditField.Value);
            Title = strcat("Thrust - TSR curve : Tidal Speed = ",title);
            ax.Title.String = Title;
            ax.YLabel.String = "Thrust (kN)";
            ax.XLabel.String = "TSR";
            
            
            s = 20;
            for lambdaRange = 1:s
                [Cp,a,a_,BetaDeg,i,ThrustRange(lambdaRange),Torque] = DesignCalcs(app.B,app.R,lambdaRange,app.V,app.r,app.c,app.gamma,app.H - 1,app.Q,app.aerofoil);
            end
              
            [m,n] = size(ThrustRange);
 
            for x = 1:s - 1
                if x == s
                    break
                end
                if abs(((ThrustRange(x) - abs(ThrustRange(x+1))) / ThrustRange(x)) * 100) > 75
                    ThrustRange(x+1) = ThrustRange(x) + ((ThrustRange(x)/100)*15);
                end
            end

%             MaxPos = find(ThrustRange==max(ThrustRange));
%             for x = 2:MaxPos
%                 if x == s
%                     break
%                 end
%                 if abs(ThrustRange(x) - ThrustRange(x-1)) > 0.2
%                     ThrustRange(x) = ThrustRange(x-1) + ((ThrustRange(x-1)/100)*15);
%                 end
%             end
%             for x = MaxPos:s-1
%                 if x == s
%                     break
%                 end
%                 if abs(ThrustRange(x) - ThrustRange(x-1)) > 0.2
%                     ThrustRange(x) = ThrustRange(x-1) - ((ThrustRange(x-1)/100)*15);
%                 end
%             end

%             for q = 2 : s - 1
%                 if ThrustRange(q) < ThrustRange(q-1) && ThrustRange(q+1) > ThrustRange(q)
%                     for k = q + 1 : s
%                         ThrustRange(q+1) = [];
%                         [m,n] = size(ThrustRange);
%                         if k == n
%                             break
%                         end
%                     end
%                     break
%                 end
%             end
%             s = n;             
            
            p = polyfit(1:s,ThrustRange,4);
            x2 = 1:s;
            y2 = polyval(p,x2);
            %plot (ax,x2,y2);
            plot (ax,1:s,ThrustRange,'+',x2,y2);
            ax.YLim = [0 (max(ThrustRange)*1.2)];
        end

        % Button pushed function: TorqueCurveButton
        function TorqueCurveButtonPushed(app, event)
            fig = uifigure();
            ax = uiaxes(fig,'Position',[10 10 525 400]);
            title = num2str(app.TidalSpeedEditField.Value);
            Title = strcat("Torque - TSR curve : Tidal Speed = ",title);
            ax.Title.String = Title;
            ax.YLabel.String = "Torque (kNm)";
            ax.XLabel.String = "TSR";
            
            
            s = 20;
            for lambdaRange = 1:s
                [Cp,a,a_,BetaDeg,i,Thrust,TorqueRange(lambdaRange)] = DesignCalcs(app.B,app.R,lambdaRange,app.V,app.r,app.c,app.gamma,app.H - 1,app.Q,app.aerofoil);
            end
              
            [m,n] = size(TorqueRange);

            for x = 1:s - 1
                if x == s
                    break
                end
                if abs(((TorqueRange(x) - abs(TorqueRange(x+1))) / TorqueRange(x)) * 100) > 75
                    TorqueRange(x+1) = TorqueRange(x) + ((TorqueRange(x)/100)*15);
                end
            end

%             for q = 2 : s - 1
%                 if TorqueRange(q) < TorqueRange(q-1) && TorqueRange(q+1) > TorqueRange(q)
%                     for k = q + 1 : s
%                         TorqueRange(q+1) = [];
%                         [m,n] = size(TorqueRange);
%                         if k == n
%                             break
%                         end
%                     end
%                     break
%                 end
%             end
%             s = n;             
            
            p = polyfit(1:s,TorqueRange,4);
            x2 = 1:s;
            y2 = polyval(p,x2);
            %plot (ax,x2,y2);
            plot (ax,1:s,TorqueRange,'+',x2,y2);
            %scatter(ax,1:s,TorqueRange);
            ax.YLim = [0 (max(TorqueRange)*1.2)];
        end

        % Value changed function: TipLossCheckBox
        function TipLossCheckBoxValueChanged(app, event)
            value = app.TipLossCheckBox.Value;
            if value == 1
                app.Q = 'Y';
            else
                app.Q = 'N';
            end
        end

        % Value changed function: AerofoilDropDown
        function AerofoilDropDownValueChanged(app, event)
            value = app.AerofoilDropDown.Value;
            if value(1) == 'N'
                app.aerofoil = 1;
            elseif value(1) == 'W'
                app.aerofoil = 2;
            else
                app.aerofoil = 1;
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            %%change answers between kW and W
            if value == 'k'
                app.PowerLabel.Visible = 'on';
                app.ThrustLabel.Visible = 'on';
                app.TorqueLabel.Visible = 'on';
                app.PowerLabel_2.Visible = 'off';
                app.ThrustLabel_2.Visible = 'off';
                app.TorqueLabel_2.Visible = 'off';
                app.EditFieldPower.Value = app.EditFieldPower.Value / 1000;
                app.EditFieldThrust.Value = app.EditFieldThrust.Value / 1000;
                app.EditFieldTorque.Value = app.EditFieldTorque.Value / 1000;
            else
                app.PowerLabel.Visible = 'off';
                app.ThrustLabel.Visible = 'off';
                app.TorqueLabel.Visible = 'off';
                app.PowerLabel_2.Visible = 'on';
                app.ThrustLabel_2.Visible = 'on';
                app.TorqueLabel_2.Visible = 'on';
                app.EditFieldPower.Value = app.EditFieldPower.Value * 1000;
                app.EditFieldThrust.Value = app.EditFieldThrust.Value * 1000;
                app.EditFieldTorque.Value = app.EditFieldTorque.Value * 1000;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BEMT_App = uifigure;
            app.BEMT_App.Name = 'Tidal Blade Designer';
            app.InputTable = uitable(app.BEMT_App);
            app.InputTable.ColumnName = {'r [m]'; 'gamma [degrees]'; 'c [m]'};
            app.InputTable.RowName = {};
            app.InputTable.ColumnEditable = true;
            app.InputTable.CellEditCallback = createCallbackFcn(app, @InputTableCellEdit, true);
            app.CalculateButton = uibutton(app.BEMT_App, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.AddRowButton = uibutton(app.BEMT_App, 'push');
            app.AddRowButton.ButtonPushedFcn = createCallbackFcn(app, @AddRowButtonPushed, true);
            app.AddRowButton.Text = 'Add Row';
            app.DeleteRowButton = uibutton(app.BEMT_App, 'push');
            app.DeleteRowButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteRowButtonPushed, true);
            app.DeleteRowButton.Text = 'Delete Row';
            app.EditField = uieditfield(app.BEMT_App, 'numeric');
            app.EditField.ValueDisplayFormat = '%.2f';
            app.EditField.Editable = 'off';
            app.OutputTable = uitable(app.BEMT_App);
            app.OutputTable.ColumnName = {'a'; 'a'' '; 'i [degrees]'; 'Beta [degrees]'};
            app.OutputTable.RowName = {};
            app.CpCurveButton = uibutton(app.BEMT_App, 'push');
            app.CpCurveButton.ButtonPushedFcn = createCallbackFcn(app, @CpCurveButtonPushed, true);
            app.CpCurveButton.Text = 'Cp Curve';
            app.PowerCurveButton = uibutton(app.BEMT_App, 'push');
            app.PowerCurveButton.ButtonPushedFcn = createCallbackFcn(app, @PowerCurveButtonPushed, true);
            app.PowerCurveButton.Text = 'Power Curve';
            app.ThrustCurveButton = uibutton(app.BEMT_App, 'push');
            app.ThrustCurveButton.ButtonPushedFcn = createCallbackFcn(app, @ThrustCurveButtonPushed, true);
            app.ThrustCurveButton.Text = 'Thrust Curve';
            app.TorqueCurveButton = uibutton(app.BEMT_App, 'push');
            app.TorqueCurveButton.ButtonPushedFcn = createCallbackFcn(app, @TorqueCurveButtonPushed, true);
            app.TorqueCurveButton.Text = 'Torque Curve';
            app.CpLabel = uilabel(app.BEMT_App);
            app.CpLabel.Text = 'Cp ';
            app.AerofoilDropDownLabel = uilabel(app.BEMT_App);
            app.AerofoilDropDownLabel.Text = 'Aerofoil';
            app.AerofoilDropDown = uidropdown(app.BEMT_App);
            app.AerofoilDropDown.Items = {'NACA 0012', 'Wottmann FX 63-137'};
            app.AerofoilDropDown.ValueChangedFcn = createCallbackFcn(app, @AerofoilDropDownValueChanged, true);
            app.AerofoilDropDown.Value = 'NACA 0012';
            app.NumberofBladesEditFieldLabel = uilabel(app.BEMT_App);
            app.NumberofBladesEditFieldLabel.Text = 'Number of Blades';
            app.NumberofBladesEditField = uieditfield(app.BEMT_App, 'numeric');
            app.NumberofBladesEditField.Limits = [1 24];
            app.NumberofBladesEditField.Value = 3;
            app.TidalSpeedEditFieldLabel = uilabel(app.BEMT_App);
            app.TidalSpeedEditFieldLabel.Text = 'Tidal Speed';
            app.TidalSpeedEditField = uieditfield(app.BEMT_App, 'numeric');
            app.TidalSpeedEditField.Limits = [0.1 8];
            app.TidalSpeedEditField.Value = 1.5;
            app.TipSpeedRatioEditFieldLabel = uilabel(app.BEMT_App);
            app.TipSpeedRatioEditFieldLabel.Text = 'Tip Speed Ratio';
            app.TipSpeedRatioEditField = uieditfield(app.BEMT_App, 'numeric');
            app.TipSpeedRatioEditField.Limits = [1 20];
            app.TipSpeedRatioEditField.Value = 8;
            app.DesignInputsLabel = uilabel(app.BEMT_App);
            app.DesignInputsLabel.Text = 'Design Inputs';
            app.DesignResultsLabel = uilabel(app.BEMT_App);
            app.DesignResultsLabel.Text = 'Design Results';
            app.BladeElementMomentumTheoryBladeDesignerLabel = uilabel(app.BEMT_App);
            app.BladeElementMomentumTheoryBladeDesignerLabel.Text = 'Blade Element Momentum Theory Blade Designer';
            app.EditFieldPower = uieditfield(app.BEMT_App, 'numeric');
            app.EditFieldPower.ValueDisplayFormat = '%.0f';
            app.EditFieldPower.Editable = 'off';
            app.PowerLabel = uilabel(app.BEMT_App);
            app.PowerLabel.Text = 'Power [kW] ';
            app.EditFieldThrust = uieditfield(app.BEMT_App, 'numeric');
            app.EditFieldThrust.ValueDisplayFormat = '%.0f';
            app.EditFieldThrust.Editable = 'off';
            app.EditFieldTorque = uieditfield(app.BEMT_App, 'numeric');
            app.EditFieldTorque.ValueDisplayFormat = '%.0f';
            app.EditFieldTorque.Editable = 'off';
            app.ThrustLabel = uilabel(app.BEMT_App);
            app.ThrustLabel.Text = 'Thrust [kN]';
            app.TorqueLabel = uilabel(app.BEMT_App);
            app.TorqueLabel.Text = 'Torque [kNm]';
            app.ExampleButton = uibutton(app.BEMT_App, 'push');
            app.ExampleButton.ButtonPushedFcn = createCallbackFcn(app, @ExampleButtonPushed, true);
            app.ExampleButton.Text = 'Example';
            app.SaveDesignButton = uibutton(app.BEMT_App, 'push');
            app.SaveDesignButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDesignButtonPushed, true);
            app.SaveDesignButton.Text = 'Save Design';
            app.RatedPowerkWLabel = uilabel(app.BEMT_App);
            app.RatedPowerkWLabel.Text = 'Rated Power [kW]';
            app.RatedPowerEditField = uieditfield(app.BEMT_App, 'numeric');
            app.RatedPowerEditField.Limits = [0.01 1000];
            app.RatedPowerEditField.Value = 300;
            app.TipLossCheckBox = uicheckbox(app.BEMT_App);
            app.TipLossCheckBox.ValueChangedFcn = createCallbackFcn(app, @TipLossCheckBoxValueChanged, true);
            app.TipLossCheckBox.Text = 'Include Tip Loss Correction';
            app.Switch = uiswitch(app.BEMT_App, 'slider');
            app.Switch.Items = {'k', ''};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'k';
            app.PowerLabel_2 = uilabel(app.BEMT_App);
            app.PowerLabel_2.Text = 'Power [W] ';
            app.ThrustLabel_2 = uilabel(app.BEMT_App);
            app.ThrustLabel_2.Text = 'Thrust [N]';
            app.TorqueLabel_2 = uilabel(app.BEMT_App);
            app.TorqueLabel_2.Text = 'Torque [Nm]';
        end
    end

    methods (Access = public)

        % Construct app
        function app = BEM_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BEMT_App)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BEMT_App)
        end
    end
end